classdef SerialReadApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OctoReaderUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SampleRateHzSpinner          matlab.ui.control.Spinner
        SampleRateHzSpinnerLabel     matlab.ui.control.Label
        CommandsgbeginreceivingdataLabel  matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        SeeAllCheckBox_2             matlab.ui.control.CheckBox
        PowerSpectrumViewLabel       matlab.ui.control.Label
        MaxSpinner                   matlab.ui.control.Spinner
        MaxSpinnerLabel              matlab.ui.control.Label
        MinSpinner                   matlab.ui.control.Spinner
        PSMinLabel                   matlab.ui.control.Label
        ClearBufferButton            matlab.ui.control.Button
        SteepnessSpinner             matlab.ui.control.Spinner
        SteepnessSpinnerLabel        matlab.ui.control.Label
        FilterModeDropDown           matlab.ui.control.DropDown
        FilterModeDropDownLabel      matlab.ui.control.Label
        LowPassSpinner               matlab.ui.control.Spinner
        LowPassSpinnerLabel          matlab.ui.control.Label
        HighPassSpinner              matlab.ui.control.Spinner
        HighPassSpinnerLabel         matlab.ui.control.Label
        SeeAllCheckBox               matlab.ui.control.CheckBox
        ViewSizesLabel               matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        NumberofInputsSpinner        matlab.ui.control.Spinner
        NumberofInputsSpinnerLabel   matlab.ui.control.Label
        SendCommandButton            matlab.ui.control.Button
        CommandEditField             matlab.ui.control.EditField
        CommandEditFieldLabel        matlab.ui.control.Label
        EndRecordingandExportButton  matlab.ui.control.Button
        BeginRecordingButton         matlab.ui.control.Button
        SerialPortDropDown           matlab.ui.control.DropDown
        SerialPortDropDownLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UIAxes8                      matlab.ui.control.UIAxes
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        NumInputs = 0% Number of Inputs
        Data % data recorded
        i % iterations
        s %serial port being opened
        recording % true to graph + record
        serialIn
        Command
        viewSize = 1
        seeAll = 0
        PSAll = 1
        PSMin = 0
        PSMax = 0
        ymax = 1
        ymin = 0
        filterSteepness = .99
        LPThresh = .5
        HPThresh = .5
        filterMode = 'Off'
        sampleRate = uint32(1000); % default: 1000
        tileGp
    end


    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginRecordingButton
        function BeginRecordingButtonPushed(app, event)
            app.recording = true;
            app.i = 1;
            app.Data = zeros(8, 1000000, 'double');
            lines = gobjects(8,1);
            plots = gobjects(8,1);
            for c = 1:app.NumInputs
                lines(c) = plot(app.RightPanel.Children.Children(c), 1:app.i, app.Data(c, 1:app.i));
                plots(c) = app.RightPanel.Children.Children(c);
            end
            
            binsize = 30;
            while (true)
                if app.recording == false
                    break;
                end

                %% "PARSE" ----- NOTE: BECAUSE WE ARE BINNING, MSP430 MUST 
                %% SPAM 255 255 254 (or whatever command) so command doesn't get lost!
                data = zeros(8, binsize, "double");
                temp = zeros(3, 1, "double");           
                numBytes = app.s.NumBytesAvailable;
                temp(1) = read(app.s, 1, "uint8");

                % check if receiving data
                if temp(1) == 255 && numBytes >= 27
                    temp(2:3) = read(app.s, 2, "uint8");
                
                    % 255 255 255 means we have data
                    if temp(2) == 255 && temp(3) == 255 
                        for bin = 1:binsize % default bin size of 30
                            for c = 1:8
                                temp = read(app.s, 3, "uint8");
                                newVal = temp(3) * 256^2 + temp(2) * 256 + temp(1);
                                
                                data(c, bin) = newVal; % add to data
                            end
                            read(app.s, 3, "uint8"); % throw away 3 values
                        end
                            app.Data(:, app.i: app.i + binsize - 1) = data;
                            app.i = app.i + binsize;  
                        
                    end
                
                end

                if (numBytes < 27) % refresh display if buffer is < 1 cycle
                    viewLength = app.viewSize * app.sampleRate; 

                    % Case: No Filter, see all data
                    if (app.seeAll == true) || (app.i - viewLength < 2)
                        doubleRate = double(app.sampleRate);
                        times = (2/doubleRate):(1/doubleRate):(app.i/doubleRate);
                        for c = 1:app.NumInputs
                            set(lines(c), 'XData', times, 'YData', app.Data(c, 1:(app.i-1))); % update plot using new data
                            plots(c).XLimMode = 'auto';
                        end
                        binsize = 30;
                    % Case: Power Spectrum, Limits Applied
                    elseif app.seeAll == false && strcmp(app.filterMode,'Power Spectrum')
                        binsize = 60;
                        numSamples = app.viewSize * app.sampleRate;
                        dataSample = app.Data(1:app.NumInputs,(app.i - numSamples):(app.i - 2 * app.viewSize))';
                        [pxx,f] = pwelch(dataSample, app.sampleRate - 1, app.sampleRate / 2, app.sampleRate * .5, app.sampleRate);

                        for c = 1:app.NumInputs
                            set(lines(c), 'XData', f/pi, 'YData', 10*log10(pxx(:,c)));
                            if app.PSAll
                                plots(c).XLimMode = 'auto';
                                app.Label.Text = "";
                                app.Label_2.Text = "";
                            elseif app.PSMin < app.PSMax
                                plots(c).XLim = [app.PSMin app.PSMax];
                                app.Label.Text = "";
                                app.Label_2.Text = "";
                            else       
                                app.Label.Text = "ERROR: Power Spectrum min must";
                                app.Label_2.Text = "be less than Power Spectrum max";
                            end
                        end
                    % Case: Filter, Limits Applied
                    elseif app.seeAll == false && (~strcmp(app.filterMode, 'Off'))

                        binsize = 100;
                        times = (app.viewSize * -1):(1/double(app.sampleRate)):0;   

                        if strcmp(app.filterMode, 'Band Pass') && (app.LPThresh <= app.HPThresh)
                            app.Label.Text = "ERROR: High pass threshold must be";
                            app.Label_2.Text = "less than low pass threshold";
                        else
                            app.Label.Text = "";
                            app.Label_2.Text = "";
                        end

                        if strcmp(app.filterMode, 'Band Pass') && (app.LPThresh > app.HPThresh)
                            [filteredData, ~] = bandpass(app.Data(1:app.NumInputs, (app.i - size(times, 2):(app.i - 1)))', [app.HPThresh app.LPThresh], app.sampleRate, 'Steepness', app.filterSteepness); 

                            for c = 1:app.NumInputs
                                set(lines(c), 'XData', times, 'YData', filteredData(:, c));
                                plots(c).XLim = [(app.viewSize * -1) 0];
                            end
                        elseif strcmp(app.filterMode, 'Low Pass')
                            [filteredData, ~] = lowpass(app.Data(1:app.NumInputs, (app.i - size(times, 2):(app.i - 1)))', app.LPThresh, app.sampleRate, 'Steepness', app.filterSteepness);          

                            for c = 1:app.NumInputs
                                set(lines(c), 'XData', times, 'YData', filteredData(:, c));
                                plots(c).XLim = [(app.viewSize * -1) 0];
                            end
                        elseif strcmp(app.filterMode, 'High Pass')
                            [filteredData, ~] = highpass(app.Data(1:app.NumInputs, (app.i - size(times, 2):(app.i - 1)))', app.HPThresh, app.sampleRate, 'Steepness', app.filterSteepness);

                            for c = 1:app.NumInputs
                                set(lines(c), 'XData', times, 'YData', filteredData(:, c));
                                plots(c).XLim = [(app.viewSize * -1) 0];
                            end
                        end
                        drawnow;

                    % Case: No Filter, Limits applied
                    else                
                        times = (app.viewSize * -1):(1/double(app.sampleRate)):0;
                        for c = 1:app.NumInputs
                            set(lines(c), 'XData', times, 'YData', app.Data(c, (app.i - size(times, 2)):(app.i - 1))); % view last __ seconds
                            plots(c).XLim = [app.viewSize * -1, 0];
                        end
                        binsize = 30;
                    end

                end
         
            end

        end

        % Button pushed function: EndRecordingandExportButton
        function EndRecordingandExportButtonPushed(app, event)
            app.recording = false;

            disp("Saving Data...")

            %% basic data
            assignin('base', 'recordedData', app.Data(1:app.NumInputs, 1:(app.i + 1)))
            assignin('base', 'timeValues', 0:(1/app.sampleRate):(app.i/app.sampleRate))
            savefig = figure;
            ax = axes;
            doubleRate = double(app.sampleRate);
            plot(ax, (2/doubleRate):(1/doubleRate):(app.i/doubleRate), app.Data(1:app.NumInputs, 1:(app.i-1)));
            xlabel(ax,'Time Elapsed (ms)');
            ylabel(ax,'Values');
            legend(ax);
            saveas(savefig, 'recordedData.fig'); 
            disp("Unfiltered data saved. (1/5)")
            
            %% filtered data
            times = (1/doubleRate):(1/doubleRate):(app.i / doubleRate);

            % low pass
            [filteredData, ~] = lowpass(app.Data(1:app.NumInputs, (1:(app.i)))', app.LPThresh, app.sampleRate, 'Steepness', app.filterSteepness);
            lpfig = figure;
            lpax = axes;
            plot(lpax, times, filteredData);
            xlabel('Time Elapsed (ms)');
            ylabel('Values');
            legend(lpax);
            saveas(lpfig, 'lowPassData.fig');
            disp("Lowpass data saved. (2/5)")

            % high pass
            [filteredData, ~] = highpass(app.Data(1:app.NumInputs, (1:(app.i)))', app.HPThresh, app.sampleRate, 'Steepness', app.filterSteepness);
            hpfig = figure;
            hpax = axes;
            plot(hpax, times, filteredData);
            xlabel('Time Elapsed (ms)');
            ylabel('Values');
            legend(hpax);
            saveas(hpfig, 'highPassData.fig');
            disp("Highpass data saved. (3/5)")

            % band pass (if applicable)
            if app.LPThresh > app.HPThresh
                [filteredData, ~] = bandpass(app.Data(1:app.NumInputs, (1:(app.i)))', [app.HPThresh app.LPThresh], app.sampleRate, 'Steepness', app.filterSteepness);
                bpfig = figure;
                bpax = axes;
                plot(bpax, times, filteredData);
                xlabel('Time Elapsed (ms)');
                ylabel('Values');
                legend(bpax);
                saveas(bpfig, 'bandPassData.fig');
                disp("Bandpass data saved. (4/5)")
            else
                disp("Bandpass parameters invalid. Data not saved. (4/5)")
            end
     
            % power spectrum
            numSamples = app.i;
            [pxx,w] = pwelch(app.Data(1:app.NumInputs,:)',numSamples);
            psfig = figure;
            psax = axes;
            psax.XTick = 0:.05:1;
            plot(w/pi, 10*log10(pxx));
            xlabel('Frequency');
            ylabel('Prevalence');
            legend(psax);
            saveas(psfig, 'powerSpectrumData.fig');
            disp("Power spectrum saved. (5/5)");
            disp("Finished Saving Data.");

            % saveas(app.RightPanel, 'gridDat.fig'); % possible future
            % feature: saving in the same format as right panel for better
            % readability


        end

        % Drop down opening function: SerialPortDropDown
        function SerialPortDropDownOpening(app, event)
            app.SerialPortDropDown.Items = serialportlist;
        end

        % Value changed function: SerialPortDropDown
        function SerialPortDropDownValueChanged(app, event)
            app.serialIn = app.SerialPortDropDown.Value;
            app.s = serialport(app.serialIn, 115200);
        end

        % Value changing function: CommandEditField
        function CommandEditFieldValueChanging(app, event)
            app.Command = event.Value;
        end

        % Button pushed function: SendCommandButton
        function SendCommandButtonPushed(app, event)
            if size(app.Command) > 0
                writeline(app.s, app.Command);

                % if we ask to receive data, receive the sampling rate first. not
                % using this method anymore, but potential future
                % implementation.
%                 if app.Command == "g"
%                     app.dataget = true;
%                 end
            end

            
        end

        % Value changed function: NumberofInputsSpinner
        function NumberofInputsSpinnerValueChanged(app, event)
            app.NumInputs = app.NumberofInputsSpinner.Value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.viewSize = round(event.Value);            
        end

        % Value changed function: SeeAllCheckBox
        function SeeAllCheckBoxValueChanged(app, event)
            app.seeAll = app.SeeAllCheckBox.Value;
        end

        % Value changed function: HighPassSpinner
        function HighPassSpinnerValueChanged(app, event)
            app.HPThresh = app.HighPassSpinner.Value;
        end

        % Value changed function: LowPassSpinner
        function LowPassSpinnerValueChanged(app, event)
            app.LPThresh = app.LowPassSpinner.Value;
        end

        % Value changed function: FilterModeDropDown
        function FilterModeDropDownValueChanged(app, event)
            app.filterMode = app.FilterModeDropDown.Value;            
        end

        % Value changed function: SteepnessSpinner
        function SteepnessSpinnerValueChanged(app, event)
            app.filterSteepness = app.SteepnessSpinner.Value;            
        end

        % Button pushed function: ClearBufferButton
        function ClearBufferButtonPushed(app, event)
            flush(app.s);
        end

        % Value changed function: SeeAllCheckBox_2
        function SeeAllCheckBox_2ValueChanged(app, event)
            app.PSAll = app.SeeAllCheckBox_2.Value;
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            app.PSMin = app.MinSpinner.Value;
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            app.PSMax = app.MaxSpinner.Value;
        end

        % Value changed function: SampleRateHzSpinner
        function SampleRateHzSpinnerValueChanged(app, event)
            app.sampleRate = app.SampleRateHzSpinner.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OctoReaderUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {641, 641};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OctoReaderUIFigure = uifigure('Visible', 'off');
            app.OctoReaderUIFigure.Name = 'OctoReader';
            app.OctoReaderUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OctoReaderUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SerialPortDropDownLabel = uilabel(app.LeftPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.LeftPanel);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortDropDownOpening, true);
            app.SerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDownValueChanged, true);
            app.SerialPortDropDown.Value = {};
            app.BeginRecordingButton = uibutton(app.LeftPanel, 'push');
            app.BeginRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @BeginRecordingButtonPushed, true);
            app.BeginRecordingButton.Text = 'Begin Recording';
            app.EndRecordingandExportButton = uibutton(app.LeftPanel, 'push');
            app.EndRecordingandExportButton.ButtonPushedFcn = createCallbackFcn(app, @EndRecordingandExportButtonPushed, true);
            app.EndRecordingandExportButton.Text = 'End Recording and Export';
            app.CommandEditFieldLabel = uilabel(app.LeftPanel);
            app.CommandEditFieldLabel.Text = 'Command';
            app.CommandEditField = uieditfield(app.LeftPanel, 'text');
            app.CommandEditField.ValueChangingFcn = createCallbackFcn(app, @CommandEditFieldValueChanging, true);
            app.SendCommandButton = uibutton(app.LeftPanel, 'push');
            app.SendCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendCommandButtonPushed, true);
            app.SendCommandButton.Text = 'Send Command';
            app.NumberofInputsSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofInputsSpinnerLabel.Text = 'Number of Inputs';
            app.NumberofInputsSpinner = uispinner(app.LeftPanel);
            app.NumberofInputsSpinner.Limits = [0 8];
            app.NumberofInputsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofInputsSpinnerValueChanged, true);
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [1 61];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.ViewSizesLabel = uilabel(app.LeftPanel);
            app.ViewSizesLabel.Text = 'View Size (s)';
            app.SeeAllCheckBox = uicheckbox(app.LeftPanel);
            app.SeeAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeeAllCheckBoxValueChanged, true);
            app.SeeAllCheckBox.Text = ' See All';
            app.HighPassSpinnerLabel = uilabel(app.LeftPanel);
            app.HighPassSpinnerLabel.Text = 'High Pass';
            app.HighPassSpinner = uispinner(app.LeftPanel);
            app.HighPassSpinner.Step = 0.01;
            app.HighPassSpinner.Limits = [1e-06 Inf];
            app.HighPassSpinner.ValueChangedFcn = createCallbackFcn(app, @HighPassSpinnerValueChanged, true);
            app.HighPassSpinner.Value = 50;
            app.LowPassSpinnerLabel = uilabel(app.LeftPanel);
            app.LowPassSpinnerLabel.Text = 'Low Pass';
            app.LowPassSpinner = uispinner(app.LeftPanel);
            app.LowPassSpinner.Step = 0.01;
            app.LowPassSpinner.Limits = [1e-09 Inf];
            app.LowPassSpinner.ValueChangedFcn = createCallbackFcn(app, @LowPassSpinnerValueChanged, true);
            app.LowPassSpinner.Value = 50;
            app.FilterModeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterModeDropDownLabel.Text = 'Filter Mode';
            app.FilterModeDropDown = uidropdown(app.LeftPanel);
            app.FilterModeDropDown.Items = {'Off', 'High Pass', 'Low Pass', 'Band Pass', 'Power Spectrum'};
            app.FilterModeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterModeDropDownValueChanged, true);
            app.FilterModeDropDown.Value = 'Off';
            app.SteepnessSpinnerLabel = uilabel(app.LeftPanel);
            app.SteepnessSpinnerLabel.Text = 'Steepness';
            app.SteepnessSpinner = uispinner(app.LeftPanel);
            app.SteepnessSpinner.Step = 0.01;
            app.SteepnessSpinner.Limits = [1e-07 0.9999999];
            app.SteepnessSpinner.ValueChangedFcn = createCallbackFcn(app, @SteepnessSpinnerValueChanged, true);
            app.SteepnessSpinner.Value = 0.99;
            app.ClearBufferButton = uibutton(app.LeftPanel, 'push');
            app.ClearBufferButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBufferButtonPushed, true);
            app.ClearBufferButton.Text = 'Clear Buffer';
            app.PSMinLabel = uilabel(app.LeftPanel);
            app.PSMinLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.LeftPanel);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.LeftPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.LeftPanel);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.PowerSpectrumViewLabel = uilabel(app.LeftPanel);
            app.PowerSpectrumViewLabel.Text = 'Power Spectrum View';
            app.SeeAllCheckBox_2 = uicheckbox(app.LeftPanel);
            app.SeeAllCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SeeAllCheckBox_2ValueChanged, true);
            app.SeeAllCheckBox_2.Text = ' See All';
            app.SeeAllCheckBox_2.Value = true;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.CommandsgbeginreceivingdataLabel = uilabel(app.LeftPanel);
            app.CommandsgbeginreceivingdataLabel.Text = {'Commands: '; '''g'': Begin receiving data'; '''s'': Stop data communication'};
            app.SampleRateHzSpinnerLabel = uilabel(app.LeftPanel);
            app.SampleRateHzSpinnerLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzSpinner = uispinner(app.LeftPanel);
            app.SampleRateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzSpinnerValueChanged, true);
            app.SampleRateHzSpinner.Value = 1000;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, '2')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, '3')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, '4')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, '5')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, '6')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout2);
            title(app.UIAxes7, '7')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout2);
            title(app.UIAxes8, '8')
            zlabel(app.UIAxes8, 'Z')
            % Show the figure after all components are created
            app.OctoReaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialReadApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OctoReaderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OctoReaderUIFigure)
        end
    end
end